classdef HeadingFromGPS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure               matlab.ui.Figure
        Grid_Main            matlab.ui.container.GridLayout
        Panel_Source         matlab.ui.container.Panel
        Grid_Source          matlab.ui.container.GridLayout
        Source_Lat_Label     matlab.ui.control.Label
        Source_Lat           matlab.ui.control.Spinner
        Source_LatHemi       matlab.ui.control.DropDown
        Source_Lon_Label     matlab.ui.control.Label
        Source_Lon           matlab.ui.control.Spinner
        Source_LonHemi       matlab.ui.control.DropDown
        Source_OtherCompute  matlab.ui.control.Button
        Panel_Target         matlab.ui.container.Panel
        Grid_Target          matlab.ui.container.GridLayout
        Target_Lat_Label     matlab.ui.control.Label
        Target_Lat           matlab.ui.control.Spinner
        Target_LatHemi       matlab.ui.control.DropDown
        Target_Lon_Label     matlab.ui.control.Label
        Target_Lon           matlab.ui.control.Spinner
        Target_LonHemi       matlab.ui.control.DropDown
        Target_OtherCompute  matlab.ui.control.Button
        Panel_Instr          matlab.ui.container.Panel
        Grid_Instr           matlab.ui.container.GridLayout
        Instr_Format_Label   matlab.ui.control.Label
        Instr_Format         matlab.ui.control.DropDown
        Instr_SF_Label       matlab.ui.control.Label
        Instr_SF             matlab.ui.control.Spinner
        Instr_Acc_Label      matlab.ui.control.Label
        Instr_Acc            matlab.ui.control.Spinner
        Panel_Run            matlab.ui.container.Panel
        Grid_Run             matlab.ui.container.GridLayout
        Run_nb_Label         matlab.ui.control.Label
        Run_nb               matlab.ui.control.Spinner
        Run_Button           matlab.ui.control.Button
        Run_Azi_Label        matlab.ui.control.Label
        Run_Azi              matlab.ui.control.TextArea
        Run_Dist_Label       matlab.ui.control.Label
        Run_Dist             matlab.ui.control.TextArea
        License              matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function [lat, lon] = Vincenty_Jitter(app, lat, lon, GPS_acc)
            % This function jitters the latitude and longitude coordinates
            % using the Vincenty formula based on the accuracy of the GPS
            
            % Randomize jitter direction and magnitude
            azi = 360 * rand(size(lat));
            del = GPS_acc * randn(size(lat));
            
            % Get jittered lat and lon
            [lat, lon] = Vincenty_Forward(app, lat, lon, del, azi);
        end
        
        function [lat, lon] = Vincenty_Forward(~, lat, lon, del, azi)
            % This function uses Vincenty's direct/forward problem to
            % jitter input coordinates a given horizontal distance and
            % heading
            
            % Define constants
            a = 6378137.0; % Radius at equator [m]
            f = 1 / 298.257223563; % Ellipsoid flattening [-]
            b = (1 - f) * a; % Radius at poles [m]
            
            % Convert angles to radians
            lat = lat * pi / 180;
            lon = mod(lon, 360) * pi / 180;
            azi = azi * pi / 180;
            
            % Run through algorithm initialization
            U1 = atan((1 - f) * tan(lat));
            sig_1 = atan2(tan(U1), cos(azi));
            sin_a = cos(U1) .* sin(azi);
            u2 = (1 - sin_a .^ 2) .* ((a ./ b) .^ 2 - 1);
            k1 = (sqrt(1 + u2) - 1) ./ (sqrt(1 + u2) + 1);
            A = (1 + 0.25 * k1 .^ 2) ./ (1 - k1);
            B = k1 .* (1 - 3 / 8 * k1 .^ 2);
            
            % Run iteration
            sig = del / b ./ A;
            ds_old = inf;
            while 1
                c2sm = cos(2 * sig_1 + sig);
                del_sig = B .* sin(sig) .* (c2sm + B / 4 .* (cos(sig) .* (-1 + 2 * c2sm .^ 2) - B / 6 .* c2sm .* (-3 + 4 * sin(sig) .^ 2) .* (-3 + 4 * c2sm .^ 2)));
                sig = del / b ./ A + del_sig;
                if max(abs(del_sig - ds_old)) < 1e-16
                    break
                end
                ds_old = del_sig;
            end
            c2sm = cos(2 * sig_1 + sig);
            
            % Get latitude and longitude of new point
            lat = atan2(sin(U1) .* cos(sig) + cos(U1) .* sin(sig) .* cos(azi), (1 - f) * sqrt(sin_a .^ 2 + (sin(U1) .* sin(sig) - cos(U1) .* cos(sig) .* cos(azi)) .^ 2));
            lambda = atan2(sin(sig) .* sin(azi), cos(U1) .* cos(sig) - sin(U1) .* sin(sig) .* cos(azi));
            C = f / 16 * (1 - sin_a .^ 2) .* (4 + f * (4 - 3 * (1 - sin_a .^ 2)));
            L = lambda - f * (1 - C) .* sin_a .* (sig + C .* sin(sig) .* (c2sm + C .* cos(sig) .* (-1 + 2 * c2sm .^ 2)));
            lon = lon + L;
            
            % Convert angles to degrees
            lat = lat * 180 / pi;
            lon = lon * 180 / pi;
        end
    
        function [del, azi] = Vincenty_Inverse(~, lat_1, lon_1, lat_2, lon_2)
            % This function uses Vincenty's inverse problem to solve for the horizontal
            % distance and azimuth/bearing from GPS point 1 to GPS point 2
            
            % Define constants
            a = 6378137.0; % Radius at equator [m]
            f = 1 / 298.257223563; % Ellipsoid flattening [-]
            b = (1 - f) * a; % Radius at poles [m]
            
            % Convert angles to radians
            lat_1 = lat_1 * pi / 180;
            lon_1 = mod(lon_1, 360) * pi / 180;
            lat_2 = lat_2 * pi / 180;
            lon_2 = mod(lon_2, 360) * pi / 180;
            
            % Run through algorithm initialization
            U1 = atan((1 - f) * tan(lat_1));
            U2 = atan((1 - f) * tan(lat_2));
            L = lon_2 - lon_1;
            
            % Run iteration
            lambda = L;
            lambda_old = inf;
            while 1
                sin_sig = sqrt((cos(U2) .* sin(lambda)) .^ 2 + (cos(U1) .* sin(U2) - sin(U1) .* cos(U2) .* cos(lambda)) .^ 2);
                cos_sig = sin(U1) .* sin(U2) + cos(U1) .* cos(U2) .* cos(lambda);
                sig = atan2(sin_sig, cos_sig);
                sin_a = cos(U1) .* cos(U2) .* sin(lambda) ./ sin_sig;
                c2sm = cos_sig - 2 * sin(U1) .* sin(U2) ./ (1 - sin_a .^ 2);
                C = f / 16 * (1 - sin_a .^ 2) .* (4 + f * (4 - 3 * (1 - sin_a .^ 2)));
                lambda = L + f * (1 - C) .* sin_a .* (sig + C .* sin_sig .* (c2sm + C .* cos_sig .* (-1 + 2 * c2sm .^ 2)));
                if max(abs(lambda - lambda_old)) < 1e-16
                    break
                end
                lambda_old = lambda;
            end
            
            % Get pointings and distance
            u2 = (1 - sin_a .^ 2) .* ((a ./ b) .^ 2 - 1);
            k1 = (sqrt(1 + u2) - 1) ./ (sqrt(1 + u2) + 1);
            A = (1 + 0.25 * k1 .^ 2) ./ (1 - k1);
            B = k1 .* (1 - 3 / 8 * k1 .^ 2);
            del_sig = B .* sin_sig .* (c2sm + B / 4 .* (cos_sig .* (-1 + 2 * c2sm .^ 2) - B / 6 .* c2sm .* (-3 + 4 * sin_sig .^ 2) .* (-3 + 4 * c2sm .^ 2)));
            del = b .* A .* (sig - del_sig);
            azi = atan2d(cos(U2) .* sin(lambda), +cos(U1) .* sin(U2) - sin(U1) .* cos(U2) .* cos(lambda));
        end
    end
    
    methods (Access = public)
        
        function ResetResults(~, app)
            % This function resets the results fields
            
            % Reset results
            app.Run_Azi.Value = 'To be calculated';
            app.Run_Dist.Value = 'To be calculated';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Figure_Startup(app, source)
            % =========================================================== %
            % Position app in screen center and lock it (no resize)
            
            % Get screen size
            sz = get(groot, 'ScreenSize');
            sw = sz(3);
            sh = sz(4);
            
            % Get current size of the app
            aw = app.Figure.Position(3);
            ah = app.Figure.Position(4);
            
            % Get update of the left position of the app
            l = (sw - aw) / 2; % Centered horizontally
            
            % Get update of the bottom position of the app
            b = (sh - ah) / 2; % Centered vertically
            
            % Update figure position and turn off resizing
            app.Figure.Position(1 : 2) = [l b];
            app.Figure.Resize = 'off';
            
            % =============================================================
            % Add license text
            app.License.Text = sprintf('Copyright \x00A9 2019, Bradley M. Conrad, Distributed under the MIT License');
            
            % =============================================================
            % Fix item data
            app.Instr_Format.ItemsData = 1 : 3;
            app.Instr_Format.Value = 2;
            app.Source_LatHemi.ItemsData = 1 : 2;
            app.Source_LatHemi.Value = 1;
            app.Source_LonHemi.ItemsData = 1 : 2;
            app.Source_LonHemi.Value = 2;
            app.Target_LatHemi.ItemsData = 1 : 2;
            app.Target_LatHemi.Value = 1;
            app.Target_LonHemi.ItemsData = 1 : 2;
            app.Target_LonHemi.Value = 2;
            
            assignin('base', 'app', app)
        end

        % Button pushed function: Source_OtherCompute, 
        % Target_OtherCompute
        function Loc_OtherButtonPushed(app, event)
            % This callback opens a dialog allowing the user to input GPS
            % coordinates inalternative formats
            
            % Disable calling button
            event.Source.Enable = 'off';
            
            % Initialize dialog application
            HeadingFromGPS_AlternativeGPSInput(app, event.Source);
        end

        % Button pushed function: Run_Button
        function Run_ButtonPushed(app, event)
            % This function runs the Monte Carlo analysis and reports the
            % results
            
            % Get GPS instrument resolution
            switch app.Instr_Format.Value
                case 1 % Decimal degree
                    sig_res = 1 / 10 ^ app.Instr_SF.Value / 2;
                case 2 % Decimal minute
                    sig_res = 1 / 60 / 10 ^ app.Instr_SF.Value / 2;
                case 3 % Decimal second
                    sig_res = 1 / 60 / 60 / 10 ^ app.Instr_SF.Value / 2;
            end
            
            % Get GPS instrument accuracy
            sig_acc = app.Instr_Acc.Value / 2;
            
            % Perturb inputs by GPS instrument resolution
            lat_source = app.Source_Lat.Value * (-1) ^ (app.Source_LatHemi.Value + 1) + sig_res * randn(app.Run_nb.Value, 1);
            lon_source = app.Source_Lon.Value * (-1) ^ (app.Source_LonHemi.Value + 1) + sig_res * randn(app.Run_nb.Value, 1);
            lat_target = app.Target_Lat.Value * (-1) ^ (app.Target_LatHemi.Value + 1) + sig_res * randn(app.Run_nb.Value, 1);
            lon_target = app.Target_Lon.Value * (-1) ^ (app.Target_LonHemi.Value + 1) + sig_res * randn(app.Run_nb.Value, 1);
            
            % Jitter inputs according to GPs instrument accuracy
            [lat_source, lon_source] = Vincenty_Jitter(app, lat_source, lon_source, sig_acc);
            [lat_target, lon_target] = Vincenty_Jitter(app, lat_target, lon_target, sig_acc);
            
            % Get distance and heading
            [dist, head] = Vincenty_Inverse(app, lat_source, lon_source, lat_target, lon_target);
            
            % Update result
            app.Run_Azi.Value = sprintf('%0.2f (%0.2f)', mod(round(mean(head), 2), 360), std(head) * norminv(0.975));
            app.Run_Dist.Value = sprintf('%0.2f (%0.2f)', mean(dist), std(dist) * norminv(0.975));
        end

        % Value changed function: Instr_Acc, Instr_Format, 
        % Instr_SF, Run_nb, Source_Lat, Source_LatHemi, Source_Lon, 
        % Source_LonHemi, Target_Lat, Target_LatHemi, Target_Lon, 
        % Target_LonHemi
        function Reset_OnChange(app, event)
            % This callback clears the results on a change of inputs
            
            % Reset results
            app.ResetResults(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'HeadingFromGPS';
            app.Grid_Main = uigridlayout(app.Figure);
            app.Panel_Source = uipanel(app.Grid_Main);
            app.Panel_Source.Title = 'Set Source Location:';
            app.Grid_Source = uigridlayout(app.Panel_Source);
            app.Source_Lat_Label = uilabel(app.Grid_Source);
            app.Source_Lat_Label.Text = 'Latitude:';
            app.Source_Lat = uispinner(app.Grid_Source);
            app.Source_Lat.Step = 0.01;
            app.Source_Lat.UpperLimitInclusive = 'off';
            app.Source_Lat.Limits = [0 90];
            app.Source_Lat.ValueDisplayFormat = '%.6f\x00b0';
            app.Source_Lat.ValueChangedFcn = createCallbackFcn(app, @Reset_OnChange, true);
            app.Source_Lat.Value = 45.384272;
            app.Source_LatHemi = uidropdown(app.Grid_Source);
            app.Source_LatHemi.Items = {'North', 'South'};
            app.Source_LatHemi.ItemsData = {'1', '2'};
            app.Source_LatHemi.ValueChangedFcn = createCallbackFcn(app, @Reset_OnChange, true);
            app.Source_LatHemi.Value = '1';
            app.Source_Lon_Label = uilabel(app.Grid_Source);
            app.Source_Lon_Label.Text = 'Longitude:';
            app.Source_Lon = uispinner(app.Grid_Source);
            app.Source_Lon.Step = 0.01;
            app.Source_Lon.UpperLimitInclusive = 'off';
            app.Source_Lon.Limits = [0 180];
            app.Source_Lon.ValueDisplayFormat = '%.6f\x00b0';
            app.Source_Lon.ValueChangedFcn = createCallbackFcn(app, @Reset_OnChange, true);
            app.Source_Lon.Value = 75.698541;
            app.Source_LonHemi = uidropdown(app.Grid_Source);
            app.Source_LonHemi.Items = {'East', 'West'};
            app.Source_LonHemi.ItemsData = {'1', '2'};
            app.Source_LonHemi.ValueChangedFcn = createCallbackFcn(app, @Reset_OnChange, true);
            app.Source_LonHemi.Value = '2';
            app.Source_OtherCompute = uibutton(app.Grid_Source, 'push');
            app.Source_OtherCompute.ButtonPushedFcn = createCallbackFcn(app, @Loc_OtherButtonPushed, true);
            app.Source_OtherCompute.Text = 'Define using alternative format';
            app.Panel_Target = uipanel(app.Grid_Main);
            app.Panel_Target.Title = 'Set Target Location:';
            app.Grid_Target = uigridlayout(app.Panel_Target);
            app.Target_Lat_Label = uilabel(app.Grid_Target);
            app.Target_Lat_Label.Text = 'Latitude:';
            app.Target_Lat = uispinner(app.Grid_Target);
            app.Target_Lat.Step = 0.01;
            app.Target_Lat.UpperLimitInclusive = 'off';
            app.Target_Lat.Limits = [0 90];
            app.Target_Lat.ValueDisplayFormat = '%.6f\x00b0';
            app.Target_Lat.ValueChangedFcn = createCallbackFcn(app, @Reset_OnChange, true);
            app.Target_Lat.Value = 45.384272;
            app.Target_LatHemi = uidropdown(app.Grid_Target);
            app.Target_LatHemi.Items = {'North', 'South'};
            app.Target_LatHemi.ItemsData = {'1', '2'};
            app.Target_LatHemi.ValueChangedFcn = createCallbackFcn(app, @Reset_OnChange, true);
            app.Target_LatHemi.Value = '1';
            app.Target_Lon_Label = uilabel(app.Grid_Target);
            app.Target_Lon_Label.Text = 'Longitude:';
            app.Target_Lon = uispinner(app.Grid_Target);
            app.Target_Lon.Step = 0.01;
            app.Target_Lon.UpperLimitInclusive = 'off';
            app.Target_Lon.Limits = [0 180];
            app.Target_Lon.ValueDisplayFormat = '%.6f\x00b0';
            app.Target_Lon.ValueChangedFcn = createCallbackFcn(app, @Reset_OnChange, true);
            app.Target_Lon.Value = 75.698541;
            app.Target_LonHemi = uidropdown(app.Grid_Target);
            app.Target_LonHemi.Items = {'East', 'West'};
            app.Target_LonHemi.ItemsData = {'1', '2'};
            app.Target_LonHemi.ValueChangedFcn = createCallbackFcn(app, @Reset_OnChange, true);
            app.Target_LonHemi.Value = '2';
            app.Target_OtherCompute = uibutton(app.Grid_Target, 'push');
            app.Target_OtherCompute.ButtonPushedFcn = createCallbackFcn(app, @Loc_OtherButtonPushed, true);
            app.Target_OtherCompute.Text = 'Define using alternative format';
            app.Panel_Instr = uipanel(app.Grid_Main);
            app.Panel_Instr.Title = 'Define GPS Properties:';
            app.Grid_Instr = uigridlayout(app.Panel_Instr);
            app.Instr_Format_Label = uilabel(app.Grid_Instr);
            app.Instr_Format_Label.Text = 'GPS Format:';
            app.Instr_Format = uidropdown(app.Grid_Instr);
            app.Instr_Format.Items = {'Decimal Degree (###.xxxx°)', 'Decimal Minute (###° ##.xxx'')', 'Decimal Second (###° ##'' ##.xx")'};
            app.Instr_Format.ItemsData = {'1', '2', '3'};
            app.Instr_Format.ValueChangedFcn = createCallbackFcn(app, @Reset_OnChange, true);
            app.Instr_Format.Value = '3';
            app.Instr_SF_Label = uilabel(app.Grid_Instr);
            app.Instr_SF_Label.Text = 'Number of decimal points in smallest GPS unit (xxx):';
            app.Instr_SF = uispinner(app.Grid_Instr);
            app.Instr_SF.Limits = [0 8];
            app.Instr_SF.RoundFractionalValues = 'on';
            app.Instr_SF.ValueDisplayFormat = '%01u';
            app.Instr_SF.ValueChangedFcn = createCallbackFcn(app, @Reset_OnChange, true);
            app.Instr_SF.Value = 3;
            app.Instr_Acc_Label = uilabel(app.Grid_Instr);
            app.Instr_Acc_Label.Text = '2-sigma GPS Accuracy [m]:';
            app.Instr_Acc = uispinner(app.Grid_Instr);
            app.Instr_Acc.Step = 0.1;
            app.Instr_Acc.Limits = [0 20];
            app.Instr_Acc.ValueDisplayFormat = '%04.1f';
            app.Instr_Acc.ValueChangedFcn = createCallbackFcn(app, @Reset_OnChange, true);
            app.Instr_Acc.Value = 5;
            app.Panel_Run = uipanel(app.Grid_Main);
            app.Panel_Run.Title = 'Run Monte Carlo:';
            app.Grid_Run = uigridlayout(app.Panel_Run);
            app.Run_nb_Label = uilabel(app.Grid_Run);
            app.Run_nb_Label.Text = 'Number of Monte Carlo draws:';
            app.Run_nb = uispinner(app.Grid_Run);
            app.Run_nb.Limits = [1000 1000000];
            app.Run_nb.ValueDisplayFormat = '%u';
            app.Run_nb.ValueChangedFcn = createCallbackFcn(app, @Reset_OnChange, true);
            app.Run_nb.Value = 100000;
            app.Run_Button = uibutton(app.Grid_Run, 'push');
            app.Run_Button.ButtonPushedFcn = createCallbackFcn(app, @Run_ButtonPushed, true);
            app.Run_Button.Text = 'GO!';
            app.Run_Azi_Label = uilabel(app.Grid_Run);
            app.Run_Azi_Label.Text = 'Azimuth/Bearing (from N) +/- 95% C.I. [°]:';
            app.Run_Azi = uitextarea(app.Grid_Run);
            app.Run_Azi.Editable = 'off';
            app.Run_Azi.Value = {'To be calculated'};
            app.Run_Dist_Label = uilabel(app.Grid_Run);
            app.Run_Dist_Label.Text = 'Separation Distance  +/- 95% C.I. [m]:';
            app.Run_Dist = uitextarea(app.Grid_Run);
            app.Run_Dist.Editable = 'off';
            app.Run_Dist.Value = {'To be calculated'};
            app.License = uilabel(app.Grid_Main);
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HeadingFromGPS(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)Figure_Startup(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end