classdef HeadingFromGPS_AlternativeGPSInput < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Figure           matlab.ui.Figure
        Grid_Main        matlab.ui.container.GridLayout
        Grid_Main_Label  matlab.ui.control.Label
        Panel_Lat        matlab.ui.container.Panel
        Grid_Lat         matlab.ui.container.GridLayout
        Lat_DMS_Label    matlab.ui.control.Label
        Lat_DMS_Deg      matlab.ui.control.Spinner
        Lat_DMS_Min      matlab.ui.control.Spinner
        Lat_DMS_Sec      matlab.ui.control.Spinner
        Lat_DMin_Label   matlab.ui.control.Label
        Lat_DMin_Deg     matlab.ui.control.Spinner
        Lat_DMin_Min     matlab.ui.control.Spinner
        Lat_DDeg_Label   matlab.ui.control.Label
        Lat_DDeg         matlab.ui.control.NumericEditField
        Panel_Lon        matlab.ui.container.Panel
        Grid_Lon         matlab.ui.container.GridLayout
        Lon_DMS_Label    matlab.ui.control.Label
        Lon_DMS_Deg      matlab.ui.control.Spinner
        Lon_DMS_Min      matlab.ui.control.Spinner
        Lon_DMS_Sec      matlab.ui.control.Spinner
        Lon_DMin_Label   matlab.ui.control.Label
        Lon_DMin_Deg     matlab.ui.control.Spinner
        Lon_DMin_Min     matlab.ui.control.Spinner
        Lon_DDeg_Label   matlab.ui.control.Label
        Lon_DDeg         matlab.ui.control.NumericEditField
        Fun_Run          matlab.ui.control.Button
    end

    
    properties (Access = public)
        CallingApp      % Calling application
        EventSource     % Source button in calling application
    end
    
    methods (Access = private)
        
        function SubmitChanges(app, varargin)
            % This function submits the changes to the calling application
            % (if existant), or saves results to the command window
            
            % Pull data
            if app.EventSource == app.CallingApp.Source_OtherCompute
                app.CallingApp.Source_Lat.Value = app.Lat_DDeg.Value;
                app.CallingApp.Source_Lon.Value = app.Lon_DDeg.Value;
            elseif app.EventSource == app.CallingApp.Target_OtherCompute
                app.CallingApp.Target_Lat.Value = app.Lat_DDeg.Value;
                app.CallingApp.Target_Lon.Value = app.Lon_DDeg.Value;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Figure_StartFcn(app, varargin)
            % This startup function detects the calling source and loads
            % existing GPS data, as necessarcy
            
            if nargin == 3
                app.CallingApp = varargin{1};
                app.EventSource = varargin{2};
                
                % Pull data
                if app.EventSource == app.CallingApp.Source_OtherCompute
                    app.Lat_DDeg.Value = app.CallingApp.Source_Lat.Value;
                    app.Lon_DDeg.Value = app.CallingApp.Source_Lon.Value;
                elseif app.EventSource == app.CallingApp.Target_OtherCompute
                    app.Lat_DDeg.Value = app.CallingApp.Target_Lat.Value;
                    app.Lon_DDeg.Value = app.CallingApp.Target_Lon.Value;
                end
            else
                delete(app)
            end
            
            % Update partners
            app.Lat_DMin_Deg.Value = floor(app.Lat_DDeg.Value);
            app.Lat_DMin_Min.Value = (app.Lat_DDeg.Value - app.Lat_DMin_Deg.Value) * 60;
            app.Lon_DMin_Deg.Value = floor(app.Lon_DDeg.Value);
            app.Lon_DMin_Min.Value = (app.Lon_DDeg.Value - app.Lon_DMin_Deg.Value) * 60;
            GPS_ValueChange(app, struct('Value', app.Lat_DMin_Deg.Value, 'PreviousValue', 0, 'Source', app.Lat_DMin_Deg))
            GPS_ValueChange(app, struct('Value', app.Lat_DMin_Min.Value, 'PreviousValue', 0, 'Source', app.Lat_DMin_Min))
            GPS_ValueChange(app, struct('Value', app.Lon_DMin_Deg.Value, 'PreviousValue', 0, 'Source', app.Lon_DMin_Deg))
            GPS_ValueChange(app, struct('Value', app.Lon_DMin_Min.Value, 'PreviousValue', 0, 'Source', app.Lon_DMin_Min))
            
            % Center window in calling object
            app.Figure.Position(1) = app.CallingApp.Figure.Position(1) + app.CallingApp.Figure.Position(3) / 2 - app.Figure.Position(3) / 2;
            app.Figure.Position(2) = app.CallingApp.Figure.Position(2) + app.CallingApp.Figure.Position(4) / 2 - app.Figure.Position(4) / 2;
        end

        % Value changed function: Lat_DMS_Deg, Lat_DMS_Min, 
        % Lat_DMS_Sec, Lat_DMin_Deg, Lat_DMin_Min, Lon_DMS_Deg, 
        % Lon_DMS_Min, Lon_DMS_Sec, Lon_DMin_Deg, Lon_DMin_Min
        function GPS_ValueChange(app, event)
            % This callback detects the source of the change and updates
            % other components, as required
            
            % Update editable partner
            switch event.Source
                case app.Lat_DMS_Deg
                    app.Lat_DMin_Deg.Value = event.Value;
                
                case app.Lat_DMS_Min
                    app.Lat_DMin_Min.Value = app.Lat_DMin_Min.Value + event.Value - event.PreviousValue;
                
                case app.Lat_DMS_Sec
                    app.Lat_DMin_Min.Value = app.Lat_DMin_Min.Value + (event.Value - event.PreviousValue) / 60;
                
                case app.Lat_DMin_Deg
                    app.Lat_DMS_Deg.Value = event.Value;
                
                case app.Lat_DMin_Min
                    app.Lat_DMS_Min.Value = floor(app.Lat_DMin_Min.Value);
                    app.Lat_DMS_Sec.Value = (app.Lat_DMin_Min.Value - app.Lat_DMS_Min.Value) * 60;
                    
                case app.Lon_DMS_Deg
                    app.Lon_DMin_Deg.Value = event.Value;
                    
                case app.Lon_DMS_Min
                    app.Lon_DMin_Min.Value = app.Lon_DMin_Min.Value + event.Value - event.PreviousValue;
                    
                case app.Lon_DMS_Sec
                    app.Lon_DMin_Min.Value = app.Lon_DMin_Min.Value + (event.Value - event.PreviousValue) / 60;
                    
                case app.Lon_DMin_Deg
                    app.Lon_DMS_Deg.Value = event.Value;
                    
                case app.Lon_DMin_Min
                    app.Lon_DMS_Min.Value = floor(app.Lon_DMin_Min.Value);
                    app.Lon_DMS_Sec.Value = (app.Lon_DMin_Min.Value - app.Lon_DMS_Min.Value) * 60;
            end
            
            % Update decimal degree
            app.Lat_DDeg.Value = app.Lat_DMin_Deg.Value + app.Lat_DMin_Min.Value / 60;
            app.Lon_DDeg.Value = app.Lon_DMin_Deg.Value + app.Lon_DMin_Min.Value / 60;
        end

        % Button pushed function: Fun_Run
        function Fun_Submit(app, event)
            % This callback runs the submit changes function and closes the
            % app
            
            % Pull data
            if app.EventSource == app.CallingApp.Source_OtherCompute
                app.CallingApp.Source_Lat.Value = app.Lat_DDeg.Value;
                app.CallingApp.Source_Lon.Value = app.Lon_DDeg.Value;
            elseif app.EventSource == app.CallingApp.Target_OtherCompute
                app.CallingApp.Target_Lat.Value = app.Lat_DDeg.Value;
                app.CallingApp.Target_Lon.Value = app.Lon_DDeg.Value;
            end
            
            % Run results changed callback on calling app
            app.CallingApp.ResetResults(app.CallingApp)
            
            % Close app
            app.EventSource.Enable = 'on';
            delete(app)
        end

        % Close request function: Figure
        function Figure_CloseFcn(app, event)
            % This callback re-enables the calling button and closes the
            % app
            
            if ~isempty(app.CallingApp)
                app.EventSource.Enable = 'on';
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Figure = uifigure('Visible', 'off');
            app.Figure.Name = 'Alternative GPS Coordinate Input';
            app.Figure.CloseRequestFcn = createCallbackFcn(app, @Figure_CloseFcn, true);
            app.Grid_Main = uigridlayout(app.Figure);
            app.Grid_Main_Label = uilabel(app.Grid_Main);
            app.Grid_Main_Label.Text = 'Input GPS coordinates in desired format';
            app.Panel_Lat = uipanel(app.Grid_Main);
            app.Panel_Lat.Title = 'Latitude:';
            app.Grid_Lat = uigridlayout(app.Panel_Lat);
            app.Lat_DMS_Label = uilabel(app.Grid_Lat);
            app.Lat_DMS_Label.Text = 'Degree-Mnute-Second:';
            app.Lat_DMS_Deg = uispinner(app.Grid_Lat);
            app.Lat_DMS_Deg.UpperLimitInclusive = 'off';
            app.Lat_DMS_Deg.Limits = [0 90];
            app.Lat_DMS_Deg.RoundFractionalValues = 'on';
            app.Lat_DMS_Deg.ValueDisplayFormat = '%u°';
            app.Lat_DMS_Deg.ValueChangedFcn = createCallbackFcn(app, @GPS_ValueChange, true);
            app.Lat_DMS_Min = uispinner(app.Grid_Lat);
            app.Lat_DMS_Min.UpperLimitInclusive = 'off';
            app.Lat_DMS_Min.Limits = [0 60];
            app.Lat_DMS_Min.RoundFractionalValues = 'on';
            app.Lat_DMS_Min.ValueDisplayFormat = '%u''';
            app.Lat_DMS_Min.ValueChangedFcn = createCallbackFcn(app, @GPS_ValueChange, true);
            app.Lat_DMS_Sec = uispinner(app.Grid_Lat);
            app.Lat_DMS_Sec.Step = 0.001;
            app.Lat_DMS_Sec.UpperLimitInclusive = 'off';
            app.Lat_DMS_Sec.Limits = [0 60];
            app.Lat_DMS_Sec.ValueDisplayFormat = '%.3f"';
            app.Lat_DMS_Sec.ValueChangedFcn = createCallbackFcn(app, @GPS_ValueChange, true);
            app.Lat_DMin_Label = uilabel(app.Grid_Lat);
            app.Lat_DMin_Label.Text = 'Decimal Minute:';
            app.Lat_DMin_Deg = uispinner(app.Grid_Lat);
            app.Lat_DMin_Deg.UpperLimitInclusive = 'off';
            app.Lat_DMin_Deg.Limits = [0 90];
            app.Lat_DMin_Deg.RoundFractionalValues = 'on';
            app.Lat_DMin_Deg.ValueDisplayFormat = '%u°';
            app.Lat_DMin_Deg.ValueChangedFcn = createCallbackFcn(app, @GPS_ValueChange, true);
            app.Lat_DMin_Min = uispinner(app.Grid_Lat);
            app.Lat_DMin_Min.Step = 1e-05;
            app.Lat_DMin_Min.UpperLimitInclusive = 'off';
            app.Lat_DMin_Min.Limits = [0 60];
            app.Lat_DMin_Min.ValueDisplayFormat = '%.5f''';
            app.Lat_DMin_Min.ValueChangedFcn = createCallbackFcn(app, @GPS_ValueChange, true);
            app.Lat_DDeg_Label = uilabel(app.Grid_Lat);
            app.Lat_DDeg_Label.Text = 'Decimal Degree:';
            app.Lat_DDeg = uieditfield(app.Grid_Lat, 'numeric');
            app.Lat_DDeg.UpperLimitInclusive = 'off';
            app.Lat_DDeg.Limits = [0 90];
            app.Lat_DDeg.ValueDisplayFormat = '%.6f°';
            app.Lat_DDeg.Editable = 'off';
            app.Panel_Lon = uipanel(app.Grid_Main);
            app.Panel_Lon.Title = 'Longitude:';
            app.Grid_Lon = uigridlayout(app.Panel_Lon);
            app.Lon_DMS_Label = uilabel(app.Grid_Lon);
            app.Lon_DMS_Label.Text = 'Degree-Mnute-Second:';
            app.Lon_DMS_Deg = uispinner(app.Grid_Lon);
            app.Lon_DMS_Deg.UpperLimitInclusive = 'off';
            app.Lon_DMS_Deg.Limits = [0 180];
            app.Lon_DMS_Deg.RoundFractionalValues = 'on';
            app.Lon_DMS_Deg.ValueDisplayFormat = '%u°';
            app.Lon_DMS_Deg.ValueChangedFcn = createCallbackFcn(app, @GPS_ValueChange, true);
            app.Lon_DMS_Min = uispinner(app.Grid_Lon);
            app.Lon_DMS_Min.UpperLimitInclusive = 'off';
            app.Lon_DMS_Min.Limits = [0 60];
            app.Lon_DMS_Min.RoundFractionalValues = 'on';
            app.Lon_DMS_Min.ValueDisplayFormat = '%u''';
            app.Lon_DMS_Min.ValueChangedFcn = createCallbackFcn(app, @GPS_ValueChange, true);
            app.Lon_DMS_Sec = uispinner(app.Grid_Lon);
            app.Lon_DMS_Sec.Step = 0.001;
            app.Lon_DMS_Sec.UpperLimitInclusive = 'off';
            app.Lon_DMS_Sec.Limits = [0 60];
            app.Lon_DMS_Sec.ValueDisplayFormat = '%.3f"';
            app.Lon_DMS_Sec.ValueChangedFcn = createCallbackFcn(app, @GPS_ValueChange, true);
            app.Lon_DMin_Label = uilabel(app.Grid_Lon);
            app.Lon_DMin_Label.Text = 'Decimal Minute:';
            app.Lon_DMin_Deg = uispinner(app.Grid_Lon);
            app.Lon_DMin_Deg.UpperLimitInclusive = 'off';
            app.Lon_DMin_Deg.Limits = [0 180];
            app.Lon_DMin_Deg.RoundFractionalValues = 'on';
            app.Lon_DMin_Deg.ValueDisplayFormat = '%u°';
            app.Lon_DMin_Deg.ValueChangedFcn = createCallbackFcn(app, @GPS_ValueChange, true);
            app.Lon_DMin_Min = uispinner(app.Grid_Lon);
            app.Lon_DMin_Min.Step = 1e-05;
            app.Lon_DMin_Min.UpperLimitInclusive = 'off';
            app.Lon_DMin_Min.Limits = [0 60];
            app.Lon_DMin_Min.ValueDisplayFormat = '%.5f''';
            app.Lon_DMin_Min.ValueChangedFcn = createCallbackFcn(app, @GPS_ValueChange, true);
            app.Lon_DDeg_Label = uilabel(app.Grid_Lon);
            app.Lon_DDeg_Label.Text = 'Decimal Degree:';
            app.Lon_DDeg = uieditfield(app.Grid_Lon, 'numeric');
            app.Lon_DDeg.UpperLimitInclusive = 'off';
            app.Lon_DDeg.Limits = [0 180];
            app.Lon_DDeg.ValueDisplayFormat = '%.6f°';
            app.Lon_DDeg.Editable = 'off';
            app.Fun_Run = uibutton(app.Grid_Main, 'push');
            app.Fun_Run.ButtonPushedFcn = createCallbackFcn(app, @Fun_Submit, true);
            app.Fun_Run.Text = 'Update GPS Coordinates';
            % Show the figure after all components are created
            app.Figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HeadingFromGPS_AlternativeGPSInput(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Figure)

            % Execute the startup function
            runStartupFcn(app, @(app)Figure_StartFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Figure)
        end
    end
end